classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainForm         matlab.ui.Figure
        TrainButton      matlab.ui.control.Button
        ConclusionText   matlab.ui.control.TextArea
        DetermineButton  matlab.ui.control.Button
        BrowseButton     matlab.ui.control.Button
        Image            matlab.ui.control.Image
    end

    
    properties (Access = private)
        trainednn % trained neural network
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
           [file_name, path_name] = uigetfile({'*.jpg;*.png','Image files'},'Select an image');
           app.Image.ImageSource = append(path_name, file_name);
           app.DetermineButton.Enable = 'on'; %enable DetermineButton only after the image was choosed.
        end

        % Button pushed function: DetermineButton
        function DetermineButtonPushed(app, event)
            test = algorytm(app.Image.ImageSource); %vector of rgb values of an image with deleted white background
            res = mean(app.trainednn(test'),2); %pass it through already trained neural network
            
            minDiff = min(abs(res-1)); % find the smallest difference from 1 (the closer the result is to 1, the higher the probability)
            
            %trying to find the row to which the minDiff value corresponds.
            %If it's the value form the first row of the 'res' vector than
            %erupean, 2 - African, 3 - Indian, 4 - East-asian.
            resText = 'The person is rather ';
            if minDiff > 0.5
                 resText = 'It is hard to determine the person`s origin';
            else
                if abs(res(1) -1) == minDiff
                    resText = append(resText, 'European');
                elseif abs(res(2) -1) == minDiff
                    resText = append(resText, 'African');
                elseif abs(res(3) - 1) == minDiff
                    resText = append(resText, 'Indian');
                elseif abs(res(4) -1 ) == minDiff
                     resText = append(resText, 'East-asian');
                end
            end
            
            %now making the result more readable, and cutting the extreme
            %values
            european = (1 - abs(res(1)-1)) * 100;
            if european < 0
                european = 0;
            end
            african = (1 - abs(res(2) -1)) * 100;
            if african < 0
                african = 0;
            end
            indian = (1 - abs(res(3) -1)) * 100;
            if indian < 0
                indian = 0;
            end
            asian = (1 - abs(res(4)-1)) * 100;
            if asian < 0
                asian = 0;
            end
        
            %ugly way to append all the text to the final result. Dont know
            %how to do it better :)
            resText = append(resText, newline, newline, "european: ", string(european), "%",  newline, "african: ",  string(african), "%", newline, "indian: ", string(indian), "%", newline, "east-asian: ", string(asian), "%");

            app.ConclusionText.Value = resText;
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            app.trainednn = nowyalg();
            msgbox('Network trained succesfully!', 'Success', 'warn');
            app.TrainButton.Enable = "off"; %disable trainButton
            app.BrowseButton.Enable = 'on'; %enable BrowseButton only after the network was trained.
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainForm = uifigure('Visible', 'off');
            app.MainForm.Name = 'Origin Determinant';
            app.Image = uiimage(app.MainForm);
            app.BrowseButton = uibutton(app.MainForm, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Enable = 'off';
            app.BrowseButton.Text = 'Browse';
            app.DetermineButton = uibutton(app.MainForm, 'push');
            app.DetermineButton.ButtonPushedFcn = createCallbackFcn(app, @DetermineButtonPushed, true);
            app.DetermineButton.Enable = 'off';
            app.DetermineButton.Text = 'Determine';
            app.ConclusionText = uitextarea(app.MainForm);
            app.ConclusionText.Editable = 'off';
            app.TrainButton = uibutton(app.MainForm, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            % Show the figure after all components are created
            app.MainForm.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainForm)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainForm)
        end
    end
end